classdef VesselDiameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VesselDiameterUIFigure          matlab.ui.Figure
        SettingsPanel                   matlab.ui.container.Panel
        OpenFileButton                  matlab.ui.control.Button
        UseSettingsCheckBox             matlab.ui.control.CheckBox
        ProcessFolderButton             matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ImageProcessingPanel            matlab.ui.container.Panel
        MaximumIntensityCheckBox        matlab.ui.control.CheckBox
        SmoothImagesCheckBox            matlab.ui.control.CheckBox
        PixeltoMicronRatioEditFieldLabel  matlab.ui.control.Label
        PixeltoMicronRatioEditField     matlab.ui.control.NumericEditField
        ZSliceSliderLabel               matlab.ui.control.Label
        ZSliceSlider                    matlab.ui.control.Slider
        WidthLineProcessingPanel        matlab.ui.container.Panel
        DrawRangeButton                 matlab.ui.control.Button
        WidthLineLengthEditFieldLabel   matlab.ui.control.Label
        WidthLineLengthEditField        matlab.ui.control.NumericEditField
        WidthLineSpacingEditFieldLabel  matlab.ui.control.Label
        WidthLineSpacingEditField       matlab.ui.control.NumericEditField
        ProcesstheseWidthLinesButton    matlab.ui.control.Button
        DiffImageButton                 matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        ProfilesButton                  matlab.ui.control.Button
        FWHMButton                      matlab.ui.control.Button
        MeanFWHMLabel                   matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        SmoothEditFieldLabel            matlab.ui.control.Label
        SmoothEditField                 matlab.ui.control.NumericEditField
        SaveResultsButton               matlab.ui.control.Button
        STDEVLabel                      matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        DiameterSpaceButton             matlab.ui.control.Button
        ViewImageButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        settings % User settings saved during use
        fileName % The file name
        filePath % The file path for the selected file
        selectedFile % The tif image file selected by the user
        currentFrame = 1 % The current frame number of the tif image displayed
        frameCount % The number of frames in this TIF file
        maxIntImage % The maximum intenstiy projection of the image
        renderFrames % The current frames ready to render
        pmr % Pixel to Micron Ratio
        savedSmoothed % Rendered smoothed frames
        savedContrast % Description
        rawFrames % Rendered raw frames, for smoothing toggling
        drawnPoly % Polygon drawn in the middle of the vessel by the user
        savedDrawnPoly % Backup Polygon, original polygon drawn
        crossLines % Cross section lines calculated by the program
        diffImage % An image that helps estimate where the greatest diameter change occurs
        frameRate % Description
        
        % Results
        rawProfiles % Lumen Intensity profiles extracted, not processed
        fwhmMean % FWHM Mean per frame
        fwhmByLine % Description
        overallFWHMMean % The overall FWHM for the entire vessel, regardless of frame
        smoothingValue % The level of smoothing applied to the results
        fwhmSTDEV % Description
        overallFwhmSTDEV % Description
        leftHits % Description
        rightHits % Description
    end

    methods (Access = private)
    
        function rerender(app, targetAxis)
            cla(targetAxis);
            imageToRender = [];
            if app.MaximumIntensityCheckBox.Value
                app.maxIntImage = max(app.renderFrames, [], 3);
                imageToRender = app.maxIntImage;
            else
                imageToRender = app.renderFrames(:,:, app.currentFrame);
            end
            
            xSize = size(imageToRender, 2) / app.pmr;
            ySize = size(imageToRender, 1) / app.pmr;
            imagesc(targetAxis, [0 xSize], [0 ySize], imageToRender);
            
            % Manipulate the Axes
            axis(targetAxis, 'tight');
            axis(targetAxis, 'square');
            ylabel(targetAxis, 'Microns');
            xlabel(targetAxis, 'Microns');
            colormap(targetAxis, 'gray');
            
            if ~isempty(app.crossLines)
                hold(targetAxis, 'on');
                dp = plot(targetAxis, app.drawnPoly(:, 1), app.drawnPoly(:, 2), 'color', 'c', 'DisplayName', 'Drawn Polygon');
                for crossLineNum = 1:numel(app.crossLines)
                    thisCrossLine = cell2mat(app.crossLines(crossLineNum));
                    cl = plot(targetAxis, thisCrossLine(:, 1), thisCrossLine(:, 2), 'color', 'r', 'DisplayName', 'Cross-Section Line');
                    text(targetAxis, thisCrossLine(1, 1), thisCrossLine(1, 2) + (0.02 * thisCrossLine(1, 2)), num2str(crossLineNum), 'FontSize', 8, 'Color', 'red');
                end
                legend(targetAxis, [dp, cl], {'Range Line', 'Width Line(s)'}, 'Location','southeastoutside', 'FontSize', 8);
            
                if and(strcmp(app.ProcesstheseWidthLinesButton.Enable, 'off'), targetAxis == app.UIAxes)
                    app.ProcesstheseWidthLinesButton.Enable = 'on'
                    app.ProfilesButton.Enable = 'off';
                    app.FWHMButton.Enable = 'off';
                    app.SaveResultsButton.Enable = 'off';
                    app.DiameterSpaceButton.Enable = 'off';
                    app.EditField.Value = 0;
                    app.EditField_2.Value = 0;
                end
            end
            
            if ~app.MaximumIntensityCheckBox.Value
                text(targetAxis, 0, 0.95, ['Frame ' num2str(app.currentFrame) ' of ' num2str(app.frameCount)], 'FontSize', 14, 'Color', 'white', 'Units', 'Normalized');
            end
            xticks(targetAxis, yticks(targetAxis));
            axis(targetAxis, 'square');
            hold(targetAxis, 'off');
        end
        
        function loadFile(app)
            % Erase all the properties
            app.currentFrame = 1 % The current frame number of the tif image displayed
            app.maxIntImage = [];
            app.renderFrames = [];
            app.pmr = [];
            app.savedSmoothed = [];
            app.savedContrast = [];
            app.rawFrames = [];
            app.drawnPoly = [];
            app.savedDrawnPoly = [];
            app.crossLines = [];
            app.diffImage = [];
            app.rawProfiles = [];
            app.fwhmMean = [];
            app.fwhmByLine = [];
            app.overallFWHMMean = [];
            app.smoothingValue = [];
            app.fwhmSTDEV = [];
            app.overallFwhmSTDEV = [];
            app.frameRate = [];
            cla(app.UIAxes);
            
            loading = waitbar(0, 'Loading the selected file...');
            for i = 1:app.frameCount
                loading = waitbar(i / app.frameCount, loading, sprintf(['Loading the selected file...\n(Frame ' num2str(i) ' of ' num2str(app.frameCount) ')']));
                app.rawFrames(:,:,i) = imread(app.selectedFile, i);
            end
            loading = waitbar(1, loading, sprintf(['Loading the selected file...\nFinishing up...']));
            app.renderFrames = app.rawFrames;
            
            % Enable everything
            defaultMaxInt = 0;
            if isempty(app.pmr)
                app.ZSliceSlider.Enable = 'on';
                app.MaximumIntensityCheckBox.Enable = 'on';
                app.SmoothImagesCheckBox.Enable = 'on';
                app.PixeltoMicronRatioEditField.Enable = 'on';
                app.DrawRangeButton.Enable = 'on';
                app.DiffImageButton.Enable = 'on';
                app.WidthLineLengthEditField.Enable = 'on';
                app.WidthLineSpacingEditField.Enable = 'on';
                app.ViewImageButton.Enable = 'on';
                app.FPSEditField.Enable = 'on';
                app.frameRate = 1;
                if and(app.UseSettingsCheckBox.Value, ~isempty(app.settings))
                    app.settings
                    settings = app.settings{1};
                    values = app.settings{2};
                    for settingNum = 1:size(settings,1)
                        setting = string(settings(settingNum));
                        value = values{settingNum};
                        switch setting
                            case "PMR"
                                disp('Setting PMR');
                                app.PixeltoMicronRatioEditField.Value = str2double(value);
                                PixeltoMicronRatioEditFieldValueChanged(app);
                            case "UseMaxIntensity"
                                disp('Setting Max Intensity');
                                app.MaximumIntensityCheckBox.Value = str2double(value);
                                defaultMaxInt = value;
                                MaximumIntensityCheckBoxValueChanged(app);
                            case "SmoothImages"
                                disp('Setting Smooth Images');
                                app.SmoothImagesCheckBox.Value = str2double(value);
                                SmoothImagesCheckBoxValueChanged(app);
                            case "LineLength"
                                disp('Setting Line Length');
                                app.WidthLineLengthEditField.Value = str2double(value);
                                WidthLineLengthEditFieldValueChanged(app);
                            case "LineSpacing"
                                disp('Setting Line Spacing');
                                app.WidthLineSpacingEditField.Value = str2double(value);
                                WidthLineSpacingEditFieldValueChanged(app);
                            case "Smoothing"
                                disp('Setting Smoothing');
                                app.SmoothEditField.Value = str2double(value);
                                app.smoothingValue = str2double(value);
                            case "PreviousLine"
                                value
                            case "FPS"
                                disp('Setting FPS');
                                app.frameRate = str2double(value);
                                app.FPSEditField.Value = str2double(value);
                        end
                    end
                else
                    app.smoothingValue = 10; 
                end
            end
            
            % Conditional Disabling based on Frame Count
            if and(app.frameCount == 1, defaultMaxInt == 0)
                app.MaximumIntensityCheckBox.Value = 1;
                app.MaximumIntensityCheckBox.Enable = 'off';
                app.ZSliceSlider.Enable = 'off';
                app.DiffImageButton.Enable = 'off';
                app.FPSEditField.Enable = 'off';
            end
            
            % Define default PMR
            if isempty(app.pmr)
                app.pmr = 1;
                app.PixeltoMicronRatioEditField.Value = 1;
            end
                                    
            rerender(app, app.UIAxes);
            app.UseSettingsCheckBox.Enable = 'off';
            close(loading);
        end
        
        function crossLines = calculateLines(app, drawnPolygon)
            linesLoad = waitbar(0, 'Please wait while the program calculates the width lines...');
            app.crossLines = {};
            numLineSegs = size(drawnPolygon, 1) - 1;
            crossLineNum = 1;
            endX = 0;
            sect = 0;
            for lineSeg = 1:numLineSegs
                waitbar(lineSeg / numLineSegs, linesLoad, sprintf(['Please wait while the program calculates the width lines...\n(Line segment ' num2str(lineSeg) ' of ' num2str(numLineSegs) ')']));
                xi = drawnPolygon(lineSeg, 1);
                xf = drawnPolygon(lineSeg + 1, 1);
                yi = drawnPolygon(lineSeg, 2);
                yf = drawnPolygon(lineSeg + 1, 2);
                
                % Determine the slope and perp intercept
                coef = polyfit([xi, xf], [yi, yf], 1);
                invM = -1/coef(1);
                
                % Determine the points of intersection between the width lines and range line
                xDist = sqrt(((xi-xf)^2)+((yi-yf)^2));
                excess = mod(xDist, app.WidthLineSpacingEditField.Value) / 2;
                
                % Determine if there should be any offset from the previous line
                if endX ~= 0
                    % Circle equation: (x-h)^2 + (y-k)^2 = (r^2)
                    % (x - endX)^2 + (y - endY)^2 = (app.spacing^2)
                    % This line equation: y = coef(1) * x + coef(2)
                    % Substitute into circle equation
                    % (x - endX)^2 + ((coef(1)*x + coef(2)) - endY)^2 = (app.spacing^2)
                    sect(1) = (sqrt(-(endX^2)*(coef(1)^2) + 2*endX*coef(1)*(endY - coef(2)) + (coef(1)^2)*(app.WidthLineSpacingEditField.Value^2) - coef(2)^2 + 2*coef(2)*endY - (endY^2) + (app.WidthLineSpacingEditField.Value^2)) + endX - coef(1)*coef(2) + coef(1)*endY)/(coef(1)^2 + 1);
                    sect(2) = (-sqrt(-(endX^2)*(coef(1)^2) + 2*endX*coef(1)*(endY - coef(2)) + (coef(1)^2)*(app.WidthLineSpacingEditField.Value^2) - coef(2)^2 + 2*coef(2)*endY - (endY^2) + (app.WidthLineSpacingEditField.Value^2)) + endX - coef(1)*coef(2) + coef(1)*endY)/(coef(1)^2 + 1);
                    sect = sect(sect > min([xi xf]) & sect < max([xi xf]));
                end
                
                if sect == 0
                    if xi > xf % Line travels from right to left
                        % excess = sqrt(((xi-x)^2) + ((yi-(coef(1)*x+coef(2))))^2))
                        % (excess^2) = (xi-x)^2 + (yi-(coef(1)*x+coef(2))))^2
                        rangeXi = (-sqrt((excess^2)*(coef(1)^2 + 1) - (xi*coef(1) - yi + coef(2))^2) + xi + yi*coef(1) - coef(1)*coef(2))/(coef(1)^2 + 1);
                        rangeXf = (sqrt((excess^2)*(coef(1)^2 + 1) - (xf*coef(1) - yf + coef(2))^2) + xf + yf*coef(1) - coef(1)*coef(2))/(coef(1)^2 + 1);
                    else % Line travels from left to right
                        rangeXi = (sqrt((excess^2)*(coef(1)^2 + 1) - (xi*coef(1) - yi + coef(2))^2) + xi + yi*coef(1) - coef(1)*coef(2))/(coef(1)^2 + 1);
                        rangeXf = (-sqrt((excess^2)*(coef(1)^2 + 1) - (xf*coef(1) - yf + coef(2))^2) + xf + yf*coef(1) - coef(1)*coef(2))/(coef(1)^2 + 1);
                    end
                else
                    rangeXi = sect;
                    rangeXf = xf;
                    excess = 0;
                end
                rangeYi = coef(1) * rangeXi + coef(2);
                rangeYf = coef(1) * rangeXf + coef(2);
                xDist = sqrt(((rangeXf-rangeXi)^2)+((rangeYf-rangeYi)^2));
                round((xDist)/(app.WidthLineSpacingEditField.Value)) + 1;
                
                pointsX = linspace(rangeXi, rangeXf, round((xDist)/(app.WidthLineSpacingEditField.Value)) + 1);
                pointsY = (coef(1) * pointsX) + coef(2);
                
                % Point slope formula: y - y1 = m (x - x1)
                % y = invM * (x - pointsX) + pointsY
                % Distance formula: dist = sqrt((x-x1)^2 + (y-y2)^2)
                % app.widthlength/2 = sqrt((x - pointsX)^2 + (y - pointsY)^2)
                % Substitute y and solve for x: 
                % app.widthlength/2 = sqrt((x - pointsX)^2 + ((invM * (x - pointsX) + pointsY) - pointsY)^2)
                % app.widthlength/2 = sqrt((x - pointsX)^2 + (invM * (x - pointsX))^2)
                % x = (+-sqrt(((invM^2)*(app.length^2))+(app.length^2))+((invM^2)*pointsX)+pointsX)/((invM^2)+1)
                lineLength = app.WidthLineLengthEditField.Value;
                newXs(:, 1) = (sqrt((lineLength^2)*(invM^2) + (lineLength^2)) + 2*pointsX*(invM^2) + 2*pointsX)/(2*((invM^2) + 1));
                newXs(:, 2) = (-sqrt((lineLength^2)*(invM^2) + (lineLength^2)) + 2*pointsX*(invM^2) + 2*pointsX)/(2*((invM^2) + 1));
                
                % Find the y values for each of these Xs
                for i = 1:size(newXs, 1)
                    y(1) = invM * (newXs(i,1) - pointsX(i)) + pointsY(i);
                    y(2) = invM * (newXs(i,2) - pointsX(i)) + pointsY(i);
                    thisCrossLine(1, 1) = newXs(i,1);
                    thisCrossLine(1, 2) = y(1);
                    thisCrossLine(2, 1) = newXs(i,2);
                    thisCrossLine(2, 2) = y(2);
                    crossLines{crossLineNum} = thisCrossLine;
                    crossLineNum = crossLineNum + 1;
                end
                 
                endX = pointsX(end);
                endY = pointsY(end);
                
                if abs(rangeXi - rangeXf) < 1
                    warndlg('This program relies on the x-axis primarily for calculations. Vertical lines may cause errors in line calculations and in the display.');
                end
                
                clear sect thisCrossLine newXs lineLength y pointsX pointsY rangeXi rangeXf xDist excess coef invM xi xf yi yf
            end
            
            close(linesLoad);
            
        end

        function tryRecalcLines(app)
            if and(~isempty(app.crossLines), ~isempty(app.drawnPoly))
                app.crossLines = calculateLines(app, app.drawnPoly);
            end
            rerender(app, app.UIAxes);
        end

        function [fwhm, profile, hitLeft, hitRight] = getFWHM(app, intensityProfile)
            % Fit a curve to the profile, find the derivative of the curve (used later for slopes)
            % then restructure the profile to look better
            profile = fit((1:numel(intensityProfile))',intensityProfile,'smoothingspline','SmoothingParam',0.07);
            profile = profile(1:numel(intensityProfile));
            profileDeriv = diff(profile);
            profile = profile - min(profile);
            profile = profile / max(profile);
            
            % Determine the intersections
            halfMax = max(profile) / 2; % Should be 0.5 for max lumen of 1
            halfMaxX = 1:numel(profile);
            halfMaxY(1:numel(profile)) = halfMax;
            xInt = intersections(halfMaxX, halfMaxY, 1:numel(profile), profile);
            
            % For the left intersection, find the first intersection that is on a postive slope
            % For the right intersection, find the last intersection that is on a negative slope
            leftFound = false;
            hitLeft = NaN;
            hitRight = NaN;
            for int = 1:numel(xInt)
                try
                    if ~leftFound
                        if profileDeriv(round(xInt(int))) > 0
                            hitLeft = xInt(int);
                            leftFound = true;
                        end
                    end
                    if profileDeriv(round(xInt(int))) < 0
                        hitRight = xInt(int);
                    end
                catch E
                end
            end
            
            fwhm = (hitRight - hitLeft) / app.pmr;
        end
        
        function updateSettings(app)
            path = erase(mfilename('fullpath'), 'VesselDiameter');
            fileID = fopen([path 'VesselDiameter_Settings.ini'],'w');
            fprintf(fileID, 'UsePrevious = %i\r\nPMR = %f\r\nUseMaxIntensity = %i\r\nSmoothImages = %i\r\nLineLength = %i\r\nLineSpacing = %i\r\nPreviousLine = %s\r\nSmoothing = %i\r\nFPS = %i',app.UseSettingsCheckBox.Value,app.PixeltoMicronRatioEditField.Value,  app.MaximumIntensityCheckBox.Value,app.SmoothImagesCheckBox.Value,app.WidthLineLengthEditField.Value,app.WidthLineSpacingEditField.Value,'DED', app.SmoothEditField.Value, app.frameRate);
            fileID = fclose(fileID);
        end
        
        % ==!!== The following functions were written by Douglas Schwarz, Updated 26 May 2017, and available at https://www.mathworks.com/matlabcentral/fileexchange/11837-fast-and-robust-curve-intersections ==!!==
        %{
        function [x0,y0,iout,jout] = intersections(x1,y1,x2,y2,robust)
            % Input checks.
            if verLessThan('matlab','7.13')
            	error(nargchk(2,5,nargin)) %#ok<NCHKN>
            else
            	narginchk(2,5)
            end
            
            % Adjustments based on number of arguments.
            switch nargin
            	case 2
            		robust = true;
            		x2 = x1;
            		y2 = y1;
            		self_intersect = true;
            	case 3
            		robust = x2;
            		x2 = x1;
            		y2 = y1;
            		self_intersect = true;
            	case 4
            		robust = true;
            		self_intersect = false;
            	case 5
            		self_intersect = false;
            end
            
            % x1 and y1 must be vectors with same number of points (at least 2).
            if sum(size(x1) > 1) ~= 1 || sum(size(y1) > 1) ~= 1 || ...
            		length(x1) ~= length(y1)
            	error('X1 and Y1 must be equal-length vectors of at least 2 points.')
            end
            % x2 and y2 must be vectors with same number of points (at least 2).
            if sum(size(x2) > 1) ~= 1 || sum(size(y2) > 1) ~= 1 || ...
            		length(x2) ~= length(y2)
            	error('X2 and Y2 must be equal-length vectors of at least 2 points.')
            end
            
            
            % Force all inputs to be column vectors.
            x1 = x1(:);
            y1 = y1(:);
            x2 = x2(:);
            y2 = y2(:);
            
            % Compute number of line segments in each curve and some differences we'll
            % need later.
            n1 = length(x1) - 1;
            n2 = length(x2) - 1;
            xy1 = [x1 y1];
            xy2 = [x2 y2];
            dxy1 = diff(xy1);
            dxy2 = diff(xy2);
            
            
            % Determine the combinations of i and j where the rectangle enclosing the
            % i'th line segment of curve 1 overlaps with the rectangle enclosing the
            % j'th line segment of curve 2.
            
            % Original method that works in old MATLAB versions, but is slower than
            % using binary singleton expansion (explicit or implicit).
            % [i,j] = find( ...
            % 	repmat(mvmin(x1),1,n2) <= repmat(mvmax(x2).',n1,1) & ...
            % 	repmat(mvmax(x1),1,n2) >= repmat(mvmin(x2).',n1,1) & ...
            % 	repmat(mvmin(y1),1,n2) <= repmat(mvmax(y2).',n1,1) & ...
            % 	repmat(mvmax(y1),1,n2) >= repmat(mvmin(y2).',n1,1));
            
            % Select an algorithm based on MATLAB version and number of line
            % segments in each curve.  We want to avoid forming large matrices for
            % large numbers of line segments.  If the matrices are not too large,
            % choose the best method available for the MATLAB version.
            if n1 > 1000 || n2 > 1000 || verLessThan('matlab','7.4')
            	% Determine which curve has the most line segments.
            	if n1 >= n2
            		% Curve 1 has more segments, loop over segments of curve 2.
            		ijc = cell(1,n2);
            		min_x1 = mvmin(x1);
            		max_x1 = mvmax(x1);
            		min_y1 = mvmin(y1);
            		max_y1 = mvmax(y1);
            		for k = 1:n2
            			k1 = k + 1;
            			ijc{k} = find( ...
            				min_x1 <= max(x2(k),x2(k1)) & max_x1 >= min(x2(k),x2(k1)) & ...
            				min_y1 <= max(y2(k),y2(k1)) & max_y1 >= min(y2(k),y2(k1)));
            			ijc{k}(:,2) = k;
            		end
            		ij = vertcat(ijc{:});
            		i = ij(:,1);
            		j = ij(:,2);
            	else
            		% Curve 2 has more segments, loop over segments of curve 1.
            		ijc = cell(1,n1);
            		min_x2 = mvmin(x2);
            		max_x2 = mvmax(x2);
            		min_y2 = mvmin(y2);
            		max_y2 = mvmax(y2);
            		for k = 1:n1
            			k1 = k + 1;
            			ijc{k}(:,2) = find( ...
            				min_x2 <= max(x1(k),x1(k1)) & max_x2 >= min(x1(k),x1(k1)) & ...
            				min_y2 <= max(y1(k),y1(k1)) & max_y2 >= min(y1(k),y1(k1)));
            			ijc{k}(:,1) = k;
            		end
            		ij = vertcat(ijc{:});
            		i = ij(:,1);
            		j = ij(:,2);
            	end
            	
            elseif verLessThan('matlab','9.1')
            	% Use bsxfun.
            	[i,j] = find( ...
            		bsxfun(@le,mvmin(x1),mvmax(x2).') & ...
            		bsxfun(@ge,mvmax(x1),mvmin(x2).') & ...
            		bsxfun(@le,mvmin(y1),mvmax(y2).') & ...
            		bsxfun(@ge,mvmax(y1),mvmin(y2).'));
            	
            else
            	% Use implicit expansion.
            	[i,j] = find( ...
            		mvmin(x1) <= mvmax(x2).' & mvmax(x1) >= mvmin(x2).' & ...
            		mvmin(y1) <= mvmax(y2).' & mvmax(y1) >= mvmin(y2).');
            	
            end
            
            
            % Find segments pairs which have at least one vertex = NaN and remove them.
            % This line is a fast way of finding such segment pairs.  We take
            % advantage of the fact that NaNs propagate through calculations, in
            % particular subtraction (in the calculation of dxy1 and dxy2, which we
            % need anyway) and addition.
            % At the same time we can remove redundant combinations of i and j in the
            % case of finding intersections of a line with itself.
            if self_intersect
            	remove = isnan(sum(dxy1(i,:) + dxy2(j,:),2)) | j <= i + 1;
            else
            	remove = isnan(sum(dxy1(i,:) + dxy2(j,:),2));
            end
            i(remove) = [];
            j(remove) = [];
            
            % Initialize matrices.  We'll put the T's and B's in matrices and use them
            % one column at a time.  AA is a 3-D extension of A where we'll use one
            % plane at a time.
            n = length(i);
            T = zeros(4,n);
            AA = zeros(4,4,n);
            AA([1 2],3,:) = -1;
            AA([3 4],4,:) = -1;
            AA([1 3],1,:) = dxy1(i,:).';
            AA([2 4],2,:) = dxy2(j,:).';
            B = -[x1(i) x2(j) y1(i) y2(j)].';
            
            % Loop through possibilities.  Trap singularity warning and then use
            % lastwarn to see if that plane of AA is near singular.  Process any such
            % segment pairs to determine if they are colinear (overlap) or merely
            % parallel.  That test consists of checking to see if one of the endpoints
            % of the curve 2 segment lies on the curve 1 segment.  This is done by
            % checking the cross product
            %
            %   (x1(2),y1(2)) - (x1(1),y1(1)) x (x2(2),y2(2)) - (x1(1),y1(1)).
            %
            % If this is close to zero then the segments overlap.
            
            % If the robust option is false then we assume no two segment pairs are
            % parallel and just go ahead and do the computation.  If A is ever singular
            % a warning will appear.  This is faster and obviously you should use it
            % only when you know you will never have overlapping or parallel segment
            % pairs.
            
            if robust
            	overlap = false(n,1);
            	warning_state = warning('off','MATLAB:singularMatrix');
            	% Use try-catch to guarantee original warning state is restored.
            	try
            		lastwarn('')
            		for k = 1:n
            			T(:,k) = AA(:,:,k)\B(:,k);
            			[unused,last_warn] = lastwarn; %#ok<ASGLU>
            			lastwarn('')
            			if strcmp(last_warn,'MATLAB:singularMatrix')
            				% Force in_range(k) to be false.
            				T(1,k) = NaN;
            				% Determine if these segments overlap or are just parallel.
            				overlap(k) = rcond([dxy1(i(k),:);xy2(j(k),:) - xy1(i(k),:)]) < eps;
            			end
            		end
            		warning(warning_state)
            	catch err
            		warning(warning_state)
            		rethrow(err)
            	end
            	% Find where t1 and t2 are between 0 and 1 and return the corresponding
            	% x0 and y0 values.
            	in_range = (T(1,:) >= 0 & T(2,:) >= 0 & T(1,:) <= 1 & T(2,:) <= 1).';
            	% For overlapping segment pairs the algorithm will return an
            	% intersection point that is at the center of the overlapping region.
            	if any(overlap)
            		ia = i(overlap);
            		ja = j(overlap);
            		% set x0 and y0 to middle of overlapping region.
            		T(3,overlap) = (max(min(x1(ia),x1(ia+1)),min(x2(ja),x2(ja+1))) + ...
            			min(max(x1(ia),x1(ia+1)),max(x2(ja),x2(ja+1)))).'/2;
            		T(4,overlap) = (max(min(y1(ia),y1(ia+1)),min(y2(ja),y2(ja+1))) + ...
            			min(max(y1(ia),y1(ia+1)),max(y2(ja),y2(ja+1)))).'/2;
            		selected = in_range | overlap;
            	else
            		selected = in_range;
            	end
            	xy0 = T(3:4,selected).';
            	
            	% Remove duplicate intersection points.
            	[xy0,index] = unique(xy0,'rows');
            	x0 = xy0(:,1);
            	y0 = xy0(:,2);
            	
            	% Compute how far along each line segment the intersections are.
            	if nargout > 2
            		sel_index = find(selected);
            		sel = sel_index(index);
            		iout = i(sel) + T(1,sel).';
            		jout = j(sel) + T(2,sel).';
            	end
            else % non-robust option
            	for k = 1:n
            		[L,U] = lu(AA(:,:,k));
            		T(:,k) = U\(L\B(:,k));
            	end
            	
            	% Find where t1 and t2 are between 0 and 1 and return the corresponding
            	% x0 and y0 values.
            	in_range = (T(1,:) >= 0 & T(2,:) >= 0 & T(1,:) < 1 & T(2,:) < 1).';
            	x0 = T(3,in_range).';
            	y0 = T(4,in_range).';
            	
            	% Compute how far along each line segment the intersections are.
            	if nargout > 2
            		iout = i(in_range) + T(1,in_range).';
            		jout = j(in_range) + T(2,in_range).';
            	end
            end
        end
            
        function y = mvmin(x)
            % Faster implementation of movmin(x,k) when k = 1.
            y = min(x(1:end-1),x(2:end));
        end
        
        function y = mvmax(x)
            % Faster implementation of movmax(x,k) when k = 1.
            y = max(x(1:end-1),x(2:end));
        end
        %}
        % ==xx== END INTERSECTIONS CODE ==xx==
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup the application according to the settings
            path = erase(mfilename('fullpath'), 'VesselDiameter');
            fileID = fopen([path 'VesselDiameter_Settings.ini'],'r')
            if fileID ~= -1
                app.settings = textscan(fileID, '%s %*[=] %s', 'CommentStyle', '#');
                
                if ~isempty(app.settings)
                    strings = app.settings{1};
                    values = app.settings{2};
                    for stringNum = 1:size(strings,1)
                        thisString = string(strings(stringNum));
                        value = cell2mat(values(stringNum));
                        switch thisString
                            case "UsePrevious"
                                app.UseSettingsCheckBox.Value = logical(value);
                                return;
                        end
                    end
                end
            end
        end

        % Button pushed function: DrawRangeButton
        function DrawRangeButtonPushed(app, event)
            figure('Name','Line Placement','NumberTitle','off');
            
            if app.MaximumIntensityCheckBox.Value
                img = app.maxIntImage;
            else
                img = app.renderFrames(:,:, app.currentFrame);
            end
            
            xSize = size(img, 2) / app.pmr;
            ySize = size(img, 1) / app.pmr;
            imagesc([0 xSize], [0 ySize], img);
            colormap 'gray';
            ylabel('Pixels');
            title('Please draw in the middle of the vessel. Right click when finished');
            axis square;
        
            % Add a feature here to prevent people from making any different sized lines
            polyRes = impoly('Closed', false);
            if ~isempty(polyRes)
                app.drawnPoly = polyRes.getPosition();
                app.savedDrawnPoly = polyRes.getPosition();
                close;
                app.crossLines = calculateLines(app, app.drawnPoly);
                rerender(app, app.UIAxes);
                
                % Close previous results
                app.ProfilesButton.Enable = 'off';
                app.FWHMButton.Enable = 'off';
                app.DiameterSpaceButton.Enable = 'off';
                app.EditField.Value = 0;
                app.EditField_2.Value = 0;
            end
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [app.fileName, app.filePath] = uigetfile('*.tif', 'Please select the file.');
            app.selectedFile = fullfile(app.filePath, app.fileName);
            app.selectedFile
            if contains(app.selectedFile, '.tif', 'IgnoreCase', true)
                app.frameCount = numel(imfinfo(app.selectedFile));
                app.ZSliceSlider.Limits = [0 app.frameCount];
                app.UIAxes.Title.String = char(app.fileName);
                loadFile(app);
            else
                warndlg('File rejected: Must end in .tif');
            end
        end

        % Value changed function: PixeltoMicronRatioEditField
        function PixeltoMicronRatioEditFieldValueChanged(app, event)
            value = app.PixeltoMicronRatioEditField.Value;
            app.pmr = value;
            
            if ~isempty(app.drawnPoly)
                app.drawnPoly = app.savedDrawnPoly ./ value;
            end
            
            tryRecalcLines(app);
            updateSettings(app);
        end

        % Value changed function: SmoothImagesCheckBox
        function SmoothImagesCheckBoxValueChanged(app, event)
            value = app.SmoothImagesCheckBox.Value
            spfilter = fspecial('gaussian', round(5*app.pmr), round(0.5*app.pmr));
            if value
                if isempty(app.savedSmoothed)
                    disp('Smoothing Images!...');
                    smoothLoad = waitbar(0, 'Please wait...');
                    for i = 1:size(app.rawFrames, 3)
                        waitbar(i / app.frameCount, smoothLoad, sprintf(['Please wait while the program smooths the images...\n(Frame ' num2str(i) ' of ' num2str(app.frameCount) ')']));
                        app.renderFrames(:,:,i) = filter2(spfilter, app.rawFrames(:,:,i));
                    end
                    close(smoothLoad);
                    app.savedSmoothed = app.renderFrames;
                else
                    app.renderFrames = app.savedSmoothed;
                end
            else
                app.renderFrames = app.rawFrames;
            end
            rerender(app, app.UIAxes);
            updateSettings(app);
        end

        % Value changing function: ZSliceSlider
        function ZSliceSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue > 0
                app.currentFrame = round(changingValue)
                rerender(app, app.UIAxes);
            end
        end

        % Value changed function: WidthLineLengthEditField
        function WidthLineLengthEditFieldValueChanged(app, event)
            value = app.WidthLineLengthEditField.Value;
            tryRecalcLines(app);
            updateSettings(app);
        end

        % Value changed function: WidthLineSpacingEditField
        function WidthLineSpacingEditFieldValueChanged(app, event)
            value = app.WidthLineSpacingEditField.Value;
            tryRecalcLines(app);
            updateSettings(app);
        end

        % Button pushed function: ProcesstheseWidthLinesButton
        function ProcesstheseWidthLinesButtonPushed(app, event)
            app.ProcesstheseWidthLinesButton.Enable = 'off';
            app.rawProfiles = [];
            app.fwhmByLine = [];
            app.fwhmMean = [];
            app.fwhmSTDEV = [];
            app.overallFWHMMean = [];
            app.overallFwhmSTDEV = [];
            intLoad = waitbar(0, 'Please wait while the program processes the width lines...');
            
            numOperations = numel(app.crossLines) * app.frameCount;
            largestProfile = 0;
            for frame = 1:app.frameCount
                if app.MaximumIntensityCheckBox.Value
                    thisImage = max(app.renderFrames, [], 3);
                else
                    thisImage = app.renderFrames(:, :, frame);
                end
                
                for crossNum = 1:numel(app.crossLines)
                    operation = crossNum + numel(app.crossLines)*(frame-1);
                    waitbar((operation / numOperations), intLoad, sprintf(['Please wait while the program processes the width lines...\nFrame ' num2str(frame) ' of ' num2str(app.frameCount) ' - Line ' num2str(crossNum) ' of ' num2str(numel(app.crossLines))]));
                    thisCrossLine = cell2mat(app.crossLines(crossNum));
                    
                    rawProfile = improfile([0 (size(thisImage, 1) / app.pmr)], [0 (size(thisImage, 2)/ app.pmr)], thisImage, [double(thisCrossLine(1, 1)) double(thisCrossLine(2, 1))], [double(thisCrossLine(1, 2)) double(thisCrossLine(2, 2))]);
                    app.rawProfiles{frame, crossNum} = rawProfile;
                    [app.fwhmByLine(frame, crossNum), profile, app.leftHits(frame, crossNum), app.rightHits(frame, crossNum)] = getFWHM(app, rawProfile);
                    
                    if numel(profile) > largestProfile
                        largestProfile = numel(profile);
                    end
                end
                thisFwhmByLine = app.fwhmByLine(frame, :);
                app.fwhmByLine(frame, :) = thisFwhmByLine;
                app.fwhmMean(frame) = nanmean(app.fwhmByLine(frame, :));
                app.fwhmSTDEV(frame) = nanstd(app.fwhmByLine(frame, :));
            end
            app.overallFWHMMean = nanmean(app.fwhmMean);
            app.overallFwhmSTDEV = nanmean(app.fwhmSTDEV);
            app.EditField.Value = double(app.overallFWHMMean);
            app.EditField_2.Value = double(app.overallFwhmSTDEV);
            
            close(intLoad);
            app.ProfilesButton.Enable = 'on';
            app.FWHMButton.Enable = 'on';
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
            app.SmoothEditField.Enable = 'on';
            app.SaveResultsButton.Enable = 'on';
            app.DiameterSpaceButton.Enable = 'on';
            app.ViewImageButton.Enable = 'on';
        end

        % Button pushed function: ProfilesButton
        function ProfilesButtonPushed(app, event)
            %answer = questdlg('Which would you like to view?', 'Submenu', 'Specific Width Line','All Width Lines','All Width Lines');
            %{
            switch answer
                case 'All Width Lines'
                    figure('Name','Intensity Profiles','NumberTitle','off');
                    hold on;
                    for frame = 1:app.frameCount
                        thisFrameProfiles = app.rawProfiles(frame, :);
                        for line = 1:numel(thisFrameProfiles)
                            thisProfile = smooth(cell2mat(thisFrameProfiles(line)), app.smoothingValue);
                            thisProfile = thisProfile ./ max(thisProfile);
                            
                            if app.frameCount == 1
                                pl = plot(1:numel(thisProfile), thisProfile, 'Color', 'black');
                                pl.Color(4) = 0.3;
                            end
                        end
                        
                        avgProfile = nanmean(avgProfile, 1);
                        if app.frameCount > 1
                            avg = plot3(1:numel(avgProfile), avgProfile, (frame * (ones(size(avgProfile)))), 'Color', 'Black');
                        else
                            avg = plot(1:numel(avgProfile), avgProfile, 'Color', 'red', 'LineWidth', 2);
                            legend([pl, avg], {'Width Line Intensity Profile', 'Average Intensity Profile'}, 'Location','southeast');
                        end
                    end
                    hold off;
                    
                    xlabel('Pixel on Width Line');
                    ylabel('Normalized Intensity');
                    zlabel('Frame');
                    grid on;
                case 'Specific Width Line'
            %}
                    if ~app.MaximumIntensityCheckBox.Value
                        frameNum = str2double(cell2mat(inputdlg('Enter the frame number you would like to view.')));
                    else
                        frameNum = 1;
                    end
                    
                    if ~isnan(frameNum)
                        lineNum = str2double(cell2mat(inputdlg('Enter the width line number you would like to view.')));
                        
                        if ~isnan(lineNum)
                            if lineNum > 0 && lineNum <= numel(app.crossLines)
                                figure('Name','Intensity Profile','NumberTitle','off');
                                hold on;
                                thisFrameProfiles = app.rawProfiles(frameNum, :);
                                thisProfile = smooth(cell2mat(thisFrameProfiles(lineNum)), app.smoothingValue);
                                thisProfile = thisProfile ./ max(thisProfile);
                                p1 = plot(1:numel(thisProfile), thisProfile, 'Color', 'black', 'LineWidth', 1.5);
                                p2 = plot([app.leftHits(frameNum, lineNum) app.leftHits(frameNum, lineNum)], ylim, 'r--', 'LineWidth', 1.5);
                                plot([app.rightHits(frameNum, lineNum) app.rightHits(frameNum, lineNum)], ylim, 'r--', 'LineWidth', 1.5);
                                legend([p1 p2], {'Profile', 'Half-Max Intersect'}, 'Location', 'northwest');
                                
                                % Scale the x Axis to match PMR
                                xticklabels(round(cellfun(@str2num,xticklabels) / app.pmr));
                                
                                title(['Frame ' num2str(frameNum) ' - Width Line ' num2str(lineNum) ': ' num2str(app.fwhmByLine(frameNum, lineNum)) ' microns']);
                                xlabel('Microns from start of width line');
                                ylabel('Normalized lumosity');
                            else
                                warndlg('Specified Line does not exist, please check frame and line number again');
                            end
                        end
                    end
            %end
        end

        % Button pushed function: FWHMButton
        function FWHMButtonPushed(app, event)
            if or(app.MaximumIntensityCheckBox.Value, app.frameCount == 1)
                answer = 'FWHM vs Time';
                frameTitle = 'FWHM vs Width Line';
                xLab = 'Line Number';
                xAxisConversion = 1;
                lines = app.fwhmByLine(1, :);
            else
                answer = questdlg('Which would you like to view?', 'Submenu', 'FWHM vs Time','FWHM per Width Line','FWHM vs Time');
                frameTitle = 'FWHM vs Time';
                xLab = 'Time (s)';
                xAxisConversion = app.frameRate;
                lines = app.fwhmMean;
           end
            
            switch answer
                case 'FWHM vs Time'
                    figure('Name',frameTitle,'NumberTitle','off');
                    hold on;
                    xlabel(xLab);
                    ylabel('FWHM (Microns)');
                    
                    lines = smooth(filloutliers(lines, 'linear', 'movmean', 3), app.smoothingValue);
                    posErr = lines + app.fwhmSTDEV';
                    negErr = lines - app.fwhmSTDEV';
                    
                    size(app.fwhmSTDEV) % 1, 40
                    size(lines) % 40, 1
                    size(posErr) % 40 40
                    
                    p1 = plot(1:numel(lines), lines, 'Color', 'black', 'LineWidth', 1.5);
                    p2 = plot(1:numel(posErr), posErr', 'Color', 'blue');
                    p2 = plot(1:numel(negErr), negErr', 'Color', 'blue');
                    p3 = scatter(1:numel(app.fwhmMean), app.fwhmMean, 8, 'k', '.'); 
                    
                    legend([p1, p3, p2], {'FWHM Value', 'Raw FWHM', '±STDEV'}, 'Location','southeast');
                    
                    axis tight;
                    grid on;
                    ylim([0 inf]);
                    xt = xticklabels
                    if xAxisConversion ~= 1
                        for labelNum = 1:size(xt)
                            newXt{labelNum} = round(str2double(cell2mat(xt(labelNum))) / xAxisConversion);
                        end
                    else
                        newXt = xt;
                    end
                    xticklabels(newXt);
                case 'FWHM per Width Line'
                    figure('Name','FWHM per Width Line','NumberTitle','off');
                    main = axes();
                    set(main, 'xticklabel', []);
                    set(main, 'xtick', []);
                    set(main, 'yticklabel', []);
                    set(main, 'ytick', []);
                    ylabel('FWHM (Microns)', 'Position', [-0.0657 0.5000 0]);
                    xlabel('Time (s)', 'Position', [0.5000 -0.0685 0]);
                    
                    height = 0.8150 / size(app.fwhmByLine, 2);
                    completedHeight = 0;
                    allAxes = [];
                    for lineNum = 1:size(app.fwhmByLine, 2)
                        thisLineRaw = app.fwhmByLine(:, lineNum);
                        thisLine = smooth(filloutliers(thisLineRaw, 'linear', 'movmean', 3), app.smoothingValue);
                        ax = axes('Position', [0.1300 (0.1100 + completedHeight) 0.7750 height]);
                        hold on;
                        plot(thisLine, 'DisplayName', ['Line ' num2str(lineNum)], 'color', rand(1,3), 'LineWidth', 1.5);
                        scatter(1:numel(thisLineRaw), thisLineRaw, 8, 'k', '.', 'DisplayName', ['Line ' num2str(lineNum) ' preprocess']);
                        
                        if completedHeight ~= 0
                            set(ax,'xticklabel',[])
                        else
                            xt = xticklabels
                            if xAxisConversion ~= 1
                                for labelNum = 1:size(xt)
                                    newXt{labelNum} = round(str2double(cell2mat(xt(labelNum))) / xAxisConversion);
                                end
                            else
                                newXt = xt;
                            end
                            xticklabels(newXt);
                        end
                        
                        legend('show');
                        allAxes = [allAxes ax];
                        
                        completedHeight = completedHeight + height;
                        grid on;
                    end
                    linkaxes(allAxes, 'x');
            end
        end

        % Value changed function: SmoothEditField
        function SmoothEditFieldValueChanged(app, event)
            value = app.SmoothEditField.Value;
            app.smoothingValue = value;
            app.overallFWHMMean = nanmean(smooth(app.fwhmMean, value));
            app.overallFwhmSTDEV = nanmean(smooth(app.fwhmSTDEV, value));
            app.EditField_2.Value = app.overallFwhmSTDEV;
            app.EditField.Value = app.overallFWHMMean;
            updateSettings(app);
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            filename = [app.filePath strrep(app.fileName, '.tif', '') '_FWHM_Results_' strrep(datestr(now,'mmmm dd yyyy HH-MM AM'), ' ', '_')];
            pleaseWait = waitbar(0, 'Saving results...');
            table1 = table((1:numel(app.fwhmMean))',((1:numel(app.fwhmMean))') ./ app.frameRate, app.fwhmMean', 'VariableNames', {'Frame', 'Time', 'FWHM'});
            table2 = table((1:size(app.fwhmByLine, 1))', ((1:size(app.fwhmByLine, 1))') ./ app.frameRate, app.fwhmByLine, app.fwhmSTDEV', 'VariableNames', {'Frame', 'Time', 'Line', 'STDEV'});
            
            smoothedFwhmMean = smooth(filloutliers(app.fwhmMean, 'linear', 'movmean', 3), app.smoothingValue);
            smoothedSTDEV = smooth(filloutliers(app.fwhmSTDEV, 'linear', 'movmean', 3), app.smoothingValue);
            for line = 1:size(app.fwhmByLine, 2)
                smoothedfwhmByLine(:, line) = smooth(filloutliers(app.fwhmByLine(:, line), 'linear', 'movmean', 3), app.smoothingValue);
            end
            table3 = table((1:numel(smoothedFwhmMean))',((1:numel(smoothedFwhmMean))') ./ app.frameRate, smoothedFwhmMean, 'VariableNames', {'Frame', 'Time' 'FWHM'});
            table4 = table((1:size(smoothedfwhmByLine, 1))', ((1:size(smoothedfwhmByLine, 1))') ./ app.frameRate, smoothedfwhmByLine, smoothedSTDEV, 'VariableNames', {'Frame', 'Time', 'Line', 'STDEV'});
            
            
            pleaseWait = waitbar(1, pleaseWait, 'Saving results...');
            writetable(table1,[filename '.xlsx'], 'Sheet', 'FWHM vs Time (raw)');
            writetable(table2,[filename '.xlsx'], 'Sheet', 'FWHM vs Line (raw)');
            writetable(table3,[filename '.xlsx'], 'Sheet', 'FWHM vs Time (smoothed)');
            writetable(table4,[filename '.xlsx'], 'Sheet', 'FWHM vs Line (smoothed)');
            questdlg('Results Saved.','Attention','Okay','Okay');
            close(pleaseWait);
        end

        % Button pushed function: ViewImageButton
        function ViewImageButtonPushed(app, event)
            f1 = figure('Name','Vessel Diameter Image','NumberTitle','off');
            maxInt = app.MaximumIntensityCheckBox.Value;
            app.MaximumIntensityCheckBox.Value = 1;
            a = axes();
            rerender(app, a);
            app.MaximumIntensityCheckBox.Value = maxInt;
        end

        % Button pushed function: DiffImageButton
        function DiffImageButtonPushed(app, event)
            if isempty(app.diffImage)
                pleaseWait = waitbar(0, 'Please wait while the program calculates the difference images.');
                sumImg = sum(app.renderFrames, 3);
                varImg = var(double(app.renderFrames), 0, 3);
                sumImg = sumImg ./ max(sumImg); % Consistent Luminescence
                varImg = varImg ./ max(varImg); % Variability in Luminescence
                changeImg = varImg - sumImg;
                changeImg(changeImg <= 0) = 0;
                changeImg = changeImg ./ nanmax(changeImg(:));
                
                middleImage = app.renderFrames(:, :, round((size(app.renderFrames, 3) / 2)));
                changeImg = changeImg .* double(max(middleImage(:)));
                
                app.diffImage = imfuse(changeImg,middleImage,'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
                close(pleaseWait);
            end
            
            figure('Name','Approximate Greatest Change','NumberTitle','off');
            imshow(app.diffImage);
            axis square;
            title(['Areas (red) of approximate greatest change']);
        end

        % Value changed function: ZSliceSlider
        function ZSliceSliderValueChanged(app, event)
            value = app.ZSliceSlider.Value;
            if value > 0
                app.currentFrame = round(value)
                rerender(app, app.UIAxes);
            end
        end

        % Value changed function: MaximumIntensityCheckBox
        function MaximumIntensityCheckBoxValueChanged(app, event)
            value = app.MaximumIntensityCheckBox.Value;
            if ~isnan(app.selectedFile)
                if value
                    app.ZSliceSlider.Enable = 'off';
                    app.DiffImageButton.Enable = 'off';
                    app.frameCount = 1;
                    app.FPSEditField.Enable = 'off';
                else
                    app.ZSliceSlider.Enable = 'on';
                    app.DiffImageButton.Enable = 'on';
                    app.FPSEditField.Enable = 'on';
                    app.frameCount = numel(imfinfo(app.selectedFile));
                end
                rerender(app, app.UIAxes);
            end
            updateSettings(app);
        end

        % Value changed function: UseSettingsCheckBox
        function UseSettingsCheckBoxValueChanged(app, event)
            value = app.UseSettingsCheckBox.Value;
            if and(~isempty(app.filePath), ~isempty(app.settings))
                if value
                    settings = app.settings{1};
                    values = app.settings{2};
                    for settingNum = 1:size(settings,1)
                        setting = string(settings(settingNum));
                        value = values(settingNum);
                        switch setting
                            case "PMR"
                                disp('Setting PMR');
                                app.PixeltoMicronRatioEditField.Value = value;
                                PixeltoMicronRatioEditFieldValueChanged(app);
                            case "UseMaxIntensity"
                                disp('Setting Max Intensity');
                                app.MaximumIntensityCheckBox.Value = value;
                                defaultMaxInt = value;
                                MaximumIntensityCheckBoxValueChanged(app);
                            case "SmoothImages"
                                disp('Setting Smooth Images');
                                app.SmoothImagesCheckBox.Value = value;
                                SmoothImagesCheckBoxValueChanged(app);
                            case "LineLength"
                                disp('Setting Line Length');
                                app.WidthLineLengthEditField.Value = value;
                                WidthLineLengthEditFieldValueChanged(app);
                            case "LineSpacing"
                                disp('Setting Line Spacing');
                                app.WidthLineSpacingEditField.Value = value;
                                WidthLineSpacingEditFieldValueChanged(app);
                            case "Smoothing"
                                disp('Setting Smoothing');
                                app.SmoothEditField.Value = value;
                                app.smoothingValue = value;
                        end
                    end
                end
            end
        end

        % Value changed function: FPSEditField
        function FPSEditFieldValueChanged(app, event)
            value = app.FPSEditField.Value;
            app.frameRate = value;
            updateSettings(app);
        end

        % Button pushed function: DiameterSpaceButton
        function DiameterSpaceButtonPushed(app, event)
            answerIsGood = false;
            % Get the baseline range from the user
            while ~answerIsGood
                answer = inputdlg(['Please define the range for baseline calculation (Format: #-#, in frames. Min: 1, Max: ' num2str(round(app.frameCount / app.frameRate)) ')']);
                if isempty(answer)
                    break;
                else
                    if contains(answer, '-')
                        answer = strsplit(strrep(string(answer), ' ', ''), '-');
                        answerNum(1) = str2double(answer(1));
                        answerNum(2) = str2double(answer(2));
                        if isnan(answerNum(1)) || fix(answerNum(1)) ~= answerNum(1) || isnan(answerNum(2)) || fix(answerNum(2)) ~= answerNum(2) 
                          disp('Please follow the formatting guidelines')
                        end
                        if and(answerNum(1) > 0, answerNum(1) < answerNum(2))
                            if answerNum(2) <= round(app.frameCount / app.frameRate)
                                answerIsGood = true;
                            else
                                disp(['There is a problem with the second timepoint. It must be < the final timepoint, frame ' num2str(round(app.frameCount / app.frameRate)) '. You supplied: ' num2str(answerNum(2))]); 
                            end
                        else
                            disp(['There is a problem with the first timepoint. It must be > 0 and < ' num2str(answerNum(2)) '. You supplied: ' num2str(answerNum(1))]); 
                        end
                    end
                end
            end
            
            if answerIsGood
                answerIsGood = false;
            
                % Determine the percent constriction in regards to the baseline over time
                for line = 1:size(app.fwhmByLine, 2)
                    thisLine = smooth(filloutliers(app.fwhmByLine(:, line), 'linear', 'movmean', 3), app.smoothingValue);
                    baseline = thisLine(answerNum(1):answerNum(2));
                    baseline = mean(baseline);
                    thisLineBased = thisLine ./ baseline;
                    thisLineBased = thisLineBased * 100;
                    theseLinesBased(line, :) = thisLineBased;
                end
                
                answer = questdlg('Would you like to view all frames or a specific frame?', 'Input Needed', 'All Frames', 'Specific Frame', 'All Frames');
                figure('Name','Relative Constriction','NumberTitle','off');
                title(['Relative Constriction over space with regards to baseline']);
                switch answer
                    case 'All Frames'
                        % HEAT MAP
                        theseLinesBased = double(theseLinesBased);
                        imagesc(theseLinesBased);
                        colorbar;
                        colormap 'gray';
                        xticklabels(cellfun(@str2num,xticklabels) / app.frameRate);
                        yticklabels((cellfun(@str2num,yticklabels) * app.WidthLineSpacingEditField.Value) - app.WidthLineSpacingEditField.Value);
                        xlabel('Time (s)');
                        ylabel('Distance from Line 1 (microns)');
                        title(['Relative Constriction over space with regards to baseline']);
                    case 'Specific Frame'
                        while ~answerIsGood
                            answer = str2double(string(inputdlg('Please define a frame of interest or type 0 for all')));
                            if isempty(answer)
                                break;
                            else
                                if isnan(answer) || fix(answer) ~= answer
                                    disp('Please follow the formatting guidelines')
                                else
                                    answerIsGood = true;
                                end
                            end
                        end
                
                        if answerIsGood
                            relativeValueAtInterest = [];
                            for line = 1:size(theseLinesBased, 1)
                                relativeValueAtInterest(line) = theseLinesBased(line, answer);
                            end
                            plot(((1:numel(relativeValueAtInterest)) * app.WidthLineSpacingEditField.Value) - app.WidthLineSpacingEditField.Value, relativeValueAtInterest, 'LineWidth', 1.5);
                            xlabel('Distance from Line 1 (Microns)');
                            ylabel('Relative Constriction %');
                            title(['Relative Constriction vs Distance at Frame ' num2str(answer)]);
                        end
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.fileName, app.filePath] = uigetfile('*.tif', 'Please select the file.');
            app.selectedFile = fullfile(app.filePath, app.fileName);
            app.selectedFile
            if contains(app.selectedFile, '.tif', 'IgnoreCase', true)
                app.frameCount = numel(imfinfo(app.selectedFile));
                app.ZSliceSlider.Limits = [0 app.frameCount];
                app.UIAxes.Title.String = char(app.fileName);
                loadFile(app);
            else
                warndlg('File rejected: Must end in .tif');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VesselDiameterUIFigure = uifigure;
            app.VesselDiameterUIFigure.Name = 'Vessel Diameter';
            app.SettingsPanel = uipanel(app.VesselDiameterUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.OpenFileButton = uibutton(app.SettingsPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.UseSettingsCheckBox = uicheckbox(app.SettingsPanel);
            app.UseSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSettingsCheckBoxValueChanged, true);
            app.UseSettingsCheckBox.Text = 'Use Settings';
            app.ProcessFolderButton = uibutton(app.SettingsPanel, 'push');
            app.ProcessFolderButton.Enable = 'off';
            app.ProcessFolderButton.Text = 'Process Folder';
            app.LoadButton = uibutton(app.SettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UIAxes = uiaxes(app.VesselDiameterUIFigure);
            title(app.UIAxes, 'Please click "Open File"')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ImageProcessingPanel = uipanel(app.VesselDiameterUIFigure);
            app.ImageProcessingPanel.Title = 'Image Processing';
            app.MaximumIntensityCheckBox = uicheckbox(app.ImageProcessingPanel);
            app.MaximumIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaximumIntensityCheckBoxValueChanged, true);
            app.MaximumIntensityCheckBox.Enable = 'off';
            app.MaximumIntensityCheckBox.Text = 'Maximum Intensity';
            app.SmoothImagesCheckBox = uicheckbox(app.ImageProcessingPanel);
            app.SmoothImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothImagesCheckBoxValueChanged, true);
            app.SmoothImagesCheckBox.Enable = 'off';
            app.SmoothImagesCheckBox.Text = 'Smooth Images';
            app.PixeltoMicronRatioEditFieldLabel = uilabel(app.ImageProcessingPanel);
            app.PixeltoMicronRatioEditFieldLabel.Text = 'Pixel to Micron Ratio';
            app.PixeltoMicronRatioEditField = uieditfield(app.ImageProcessingPanel, 'numeric');
            app.PixeltoMicronRatioEditField.ValueDisplayFormat = '%.2f';
            app.PixeltoMicronRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PixeltoMicronRatioEditFieldValueChanged, true);
            app.PixeltoMicronRatioEditField.Enable = 'off';
            app.ZSliceSliderLabel = uilabel(app.ImageProcessingPanel);
            app.ZSliceSliderLabel.Text = 'Z-Slice';
            app.ZSliceSlider = uislider(app.ImageProcessingPanel);
            app.ZSliceSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliceSliderValueChanged, true);
            app.ZSliceSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliceSliderValueChanging, true);
            app.ZSliceSlider.Enable = 'off';
            app.WidthLineProcessingPanel = uipanel(app.VesselDiameterUIFigure);
            app.WidthLineProcessingPanel.Title = 'Width Line Processing';
            app.DrawRangeButton = uibutton(app.WidthLineProcessingPanel, 'push');
            app.DrawRangeButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRangeButtonPushed, true);
            app.DrawRangeButton.Enable = 'off';
            app.DrawRangeButton.Text = 'Draw Range';
            app.WidthLineLengthEditFieldLabel = uilabel(app.WidthLineProcessingPanel);
            app.WidthLineLengthEditFieldLabel.Text = 'Width Line Length';
            app.WidthLineLengthEditField = uieditfield(app.WidthLineProcessingPanel, 'numeric');
            app.WidthLineLengthEditField.RoundFractionalValues = 'on';
            app.WidthLineLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthLineLengthEditFieldValueChanged, true);
            app.WidthLineLengthEditField.Enable = 'off';
            app.WidthLineLengthEditField.Value = 10;
            app.WidthLineSpacingEditFieldLabel = uilabel(app.WidthLineProcessingPanel);
            app.WidthLineSpacingEditFieldLabel.Text = 'Width Line Spacing';
            app.WidthLineSpacingEditField = uieditfield(app.WidthLineProcessingPanel, 'numeric');
            app.WidthLineSpacingEditField.RoundFractionalValues = 'on';
            app.WidthLineSpacingEditField.ValueDisplayFormat = '%.0f';
            app.WidthLineSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @WidthLineSpacingEditFieldValueChanged, true);
            app.WidthLineSpacingEditField.Enable = 'off';
            app.WidthLineSpacingEditField.Value = 10;
            app.ProcesstheseWidthLinesButton = uibutton(app.WidthLineProcessingPanel, 'push');
            app.ProcesstheseWidthLinesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesstheseWidthLinesButtonPushed, true);
            app.ProcesstheseWidthLinesButton.Enable = 'off';
            app.ProcesstheseWidthLinesButton.Text = 'Process these Width Lines';
            app.DiffImageButton = uibutton(app.WidthLineProcessingPanel, 'push');
            app.DiffImageButton.ButtonPushedFcn = createCallbackFcn(app, @DiffImageButtonPushed, true);
            app.DiffImageButton.Enable = 'off';
            app.DiffImageButton.Text = 'Diff Image';
            app.ResultsPanel = uipanel(app.VesselDiameterUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ProfilesButton = uibutton(app.ResultsPanel, 'push');
            app.ProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProfilesButtonPushed, true);
            app.ProfilesButton.Enable = 'off';
            app.ProfilesButton.Text = 'Profiles';
            app.FWHMButton = uibutton(app.ResultsPanel, 'push');
            app.FWHMButton.ButtonPushedFcn = createCallbackFcn(app, @FWHMButtonPushed, true);
            app.FWHMButton.Enable = 'off';
            app.FWHMButton.Text = 'FWHM';
            app.MeanFWHMLabel = uilabel(app.ResultsPanel);
            app.MeanFWHMLabel.Text = 'Mean FWHM:';
            app.EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.SmoothEditFieldLabel = uilabel(app.ResultsPanel);
            app.SmoothEditFieldLabel.Text = 'Smooth ';
            app.SmoothEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.SmoothEditField.Limits = [1 50];
            app.SmoothEditField.ValueDisplayFormat = '%.0f';
            app.SmoothEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothEditFieldValueChanged, true);
            app.SmoothEditField.Enable = 'off';
            app.SmoothEditField.Value = 10;
            app.SaveResultsButton = uibutton(app.ResultsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.STDEVLabel = uilabel(app.ResultsPanel);
            app.STDEVLabel.Text = 'STDEV:';
            app.EditField_2 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.FPSEditFieldLabel = uilabel(app.ResultsPanel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.FPSEditField.Limits = [1 Inf];
            app.FPSEditField.ValueChangedFcn = createCallbackFcn(app, @FPSEditFieldValueChanged, true);
            app.FPSEditField.Enable = 'off';
            app.FPSEditField.Value = 1;
            app.DiameterSpaceButton = uibutton(app.ResultsPanel, 'push');
            app.DiameterSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @DiameterSpaceButtonPushed, true);
            app.DiameterSpaceButton.Enable = 'off';
            app.DiameterSpaceButton.Text = 'Diameter-Space';
            app.ViewImageButton = uibutton(app.VesselDiameterUIFigure, 'push');
            app.ViewImageButton.ButtonPushedFcn = createCallbackFcn(app, @ViewImageButtonPushed, true);
            app.ViewImageButton.Enable = 'off';
            app.ViewImageButton.Text = 'View Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VesselDiameter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VesselDiameterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VesselDiameterUIFigure)
        end
    end
end